classdef FaultManagementExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FaultManagementAppUIFigure      matlab.ui.Figure
        DataDictionaryFileEditField     matlab.ui.control.EditField
        AddFaultButton                  matlab.ui.control.Button
        DeleteFaultButton               matlab.ui.control.Button
        DataDictionaryFileLabel         matlab.ui.control.Label
        FaultTable                      matlab.ui.control.Table
        SaveDataButton                  matlab.ui.control.Button
        SelectDataDictionaryFileButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        ddFile % Fault Manager Data Dictionary File
        ddDataObj
        FaultData
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ddFile = Simulink.data.dictionary.open('FaultManagement_DD.sldd');
            app.ddDataObj = getSection(app.ddFile,'Design Data');
            app.FaultData = getValue(getEntry(app.ddDataObj,'FaultData'));
            app.FaultTable.Data = struct2table(app.FaultData);
            % Enable Save Data Button
            app.SaveDataButton.Enable = true;
        end

        % Button pushed function: SelectDataDictionaryFileButton
        function SelectDataDictionaryFileButtonPushed(app, event)
            [file,~] = uigetfile('*.sldd');
            if isequal(file,0)
                disp('User Cancelled The Operation');
            else
                app.DataDictionaryFileEditField.Value = file;
                %app.ddFile = fullfile(path,file);           
                app.ddFile = Simulink.data.dictionary.open(file);
                app.ddDataObj = getSection(app.ddFile,'Design Data');
                app.FaultData = getValue(getEntry(app.ddDataObj,'FaultData'));
                app.FaultTable.Data = struct2table(app.FaultData);
                % Enable Save Data Button
                app.SaveDataButton.Enable = true;
            end
        end

        % Button pushed function: AddFaultButton
        function AddFaultButtonPushed(app, event)
            UserInput = inputdlg({'Enter Fault Name:','Enter Fault ID:'},'Add New Fault',[1 50],{'FaultName','0'});
            if ~isempty(UserInput)
                newFlt = struct('FaultName',UserInput{1},'ID',uint16(str2double(UserInput{2})),'Enable',true,'Sticky',true,'TimeToFail',single(1),'TimeToPass',single(2),'SampleTime',single(0.1),'Suspected',false,'Active',false,'Occured',false);
                tempTable = [app.FaultTable.Data;struct2table(newFlt)];
                a = unique(tempTable(:,1), 'stable');
                if ~isequal(size(a), size(tempTable(:,1)))
                        errordlg('FaultName must be unique.','Error');
                else
                    a = unique(tempTable(:,2), 'stable');
                    if ~isequal(size(a), size(tempTable(:,2)))
                            errordlg('Fault IDs must be unique.','Error');
                    else
                        app.FaultTable.Data = tempTable;
                    end
                end
                app.FaultData = table2struct(app.FaultTable.Data);
            else
                % Show prompt
            end
        end

        % Button pushed function: DeleteFaultButton
        function DeleteFaultButtonPushed(app, event)
            if ~isempty(app.FaultTable.Selection)
                raw = app.FaultTable.Selection(1);
                app.FaultTable.Data(raw,:) = [];
            end
        end

        % Cell edit callback: FaultTable
        function FaultTableCellEdit(app, event)
            switch (event.Indices(2))
                case 1 % Fault Column
                    a = unique(app.FaultTable.Data(:,event.Indices(2)), 'stable');
                    if ~isequal(size(a), size(app.FaultTable.Data(:,event.Indices(2))))
                        errordlg('FaultNames must be unique','Error');
                        app.FaultTable.Data(event.Indices(1),event.Indices(2)).FaultName = {event.PreviousData};
                    end

                case 2 % ID Column
                    a = unique(app.FaultTable.Data(:,event.Indices(2)), 'stable');
                    if ~isequal(size(a), size(app.FaultTable.Data(:,event.Indices(2))))
                        errordlg('Fault IDs must be unique.','Error');
                        app.FaultTable.Data(event.Indices(1),event.Indices(2)).ID = event.PreviousData;
                    end
                case 3 % Enable Column

                case 4 % Sticky Column

                case 5 % TimeToFail Column

                case 6 % TimeToPass Column

                case 7 % Sample Time 

            end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            fltDataObj = getEntry(app.ddDataObj, 'FaultData');
            app.FaultData = table2struct(app.FaultTable.Data);
            setValue(fltDataObj, app.FaultData);
            for i = 1:length(app.FaultData)
                temp = app.FaultData(i);
                fltName = app.FaultData(i).FaultName;
                NewFaultInit.(fltName) = rmfield(temp,'FaultName');
                enChk = find(app.ddDataObj,'Name',fltName);
                newFaultSignal = Simulink.Signal;
                newFaultSignal.DataType = 'Bus: Fault_Bus';
                newFaultSignal.Complexity = 'real';
                newFaultSignal.Dimensions = 1;
                newFaultSignal.InitialValue = ['FaultInit.',fltName];
                newFaultSignal.DimensionsMode = 'fixed';
                newFaultSignal.CoderInfo.StorageClass = 'ExportedGlobal';
                newFaultSignal.Description = '';
                if ~isempty(enChk)
                   setValue(enChk,newFaultSignal);
                else
                   addEntry(app.ddDataObj,app.FaultData(i).FaultName,newFaultSignal)
                end
            end
            setValue(getEntry(app.ddDataObj,'FaultInit'),NewFaultInit);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FaultManagementAppUIFigure = uifigure('Visible', 'off');
            app.FaultManagementAppUIFigure.Name = 'Fault Management App';
            app.SelectDataDictionaryFileButton = uibutton(app.FaultManagementAppUIFigure, 'push');
            app.SelectDataDictionaryFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataDictionaryFileButtonPushed, true);
            app.SelectDataDictionaryFileButton.Tooltip = {'Point to Data Dictionary for Fault Manager'};
            app.SelectDataDictionaryFileButton.Text = 'Select Data Dictionary File';
            app.SaveDataButton = uibutton(app.FaultManagementAppUIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Tooltip = {'Save Fault Manager Data Dictionary'};
            app.SaveDataButton.Text = 'Save Data';
            app.FaultTable = uitable(app.FaultManagementAppUIFigure);
            app.FaultTable.ColumnName = {'Fault'; 'ID'; 'Enable'; 'Sticky'; 'TimeToFail'; 'TimeToPass'; 'SampleTime'; 'Suspected'; 'Active'; 'Occured'};
            app.FaultTable.RowName = {};
            app.FaultTable.ColumnSortable = true;
            app.FaultTable.SelectionType = 'row';
            app.FaultTable.ColumnEditable = true;
            app.FaultTable.CellEditCallback = createCallbackFcn(app, @FaultTableCellEdit, true);
            app.FaultTable.Tooltip = {'List of Faults  Available in Fault Manager'};
            app.FaultTable.Tag = 'FaultTable';
            app.DataDictionaryFileLabel = uilabel(app.FaultManagementAppUIFigure);
            app.DataDictionaryFileLabel.Text = 'Data Dictionary File: ';
            app.DeleteFaultButton = uibutton(app.FaultManagementAppUIFigure, 'push');
            app.DeleteFaultButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFaultButtonPushed, true);
            app.DeleteFaultButton.Text = 'Delete Fault';
            app.AddFaultButton = uibutton(app.FaultManagementAppUIFigure, 'push');
            app.AddFaultButton.ButtonPushedFcn = createCallbackFcn(app, @AddFaultButtonPushed, true);
            app.AddFaultButton.Text = 'Add Fault';
            app.DataDictionaryFileEditField = uieditfield(app.FaultManagementAppUIFigure, 'text');
            app.DataDictionaryFileEditField.Editable = 'off';
            app.DataDictionaryFileEditField.Tooltip = {'Data Dictionary for Fault Manager'};
            app.DataDictionaryFileEditField.Placeholder = 'Select Data Dictionary File';
            % Show the figure after all components are created
            app.FaultManagementAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FaultManagementExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FaultManagementAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FaultManagementAppUIFigure)
        end
    end
end